classdef SaccadeAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        CurrentFileEditField            matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        ExperimentDropDownLabel         matlab.ui.control.Label
        ExperimentDropDown              matlab.ui.control.DropDown
        OptionsPanel                    matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        BlinkoffsetEditFieldLabel       matlab.ui.control.Label
        BlinkoffsetEditField            matlab.ui.control.NumericEditField
        ScreenResolutionEditFieldLabel  matlab.ui.control.Label
        ScreenResolutionCols            matlab.ui.control.NumericEditField
        ScreenResolutionRows            matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MinAmplitudeSpinnerLabel        matlab.ui.control.Label
        MinAmplitudeSpinner             matlab.ui.control.Spinner
        MinDurationSpinnerLabel         matlab.ui.control.Label
        MinDurationSpinner              matlab.ui.control.Spinner
        MaxAmplitudeSpinnerLabel        matlab.ui.control.Label
        MaxAmplitudeSpinner             matlab.ui.control.Spinner
        MaxDurationSpinnerLabel         matlab.ui.control.Label
        MaxDurationSpinner              matlab.ui.control.Spinner
        FiltersLabel                    matlab.ui.control.Label
        ReportLabel                     matlab.ui.control.Label
        NeuroSenseJointLabSienaSIItalyLabel  matlab.ui.control.Label
        SavetableButton                 matlab.ui.control.StateButton
        RemoveselectedsaccadeButton     matlab.ui.control.Button
        SaccTypeDropDownLabel           matlab.ui.control.Label
        SaccTypeDropDown                matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
    end

    
    properties (Access = private)
        experiment % Description
    end
    
    methods (Access = private)
                       
        function plot_signal(app)
            
            app.UIAxes.cla
            hold(app.UIAxes, 'on')
            
            gaze_color = [0 0 1];
            center_color = [0.5 0.5 0.5];
            target_color = [1 0 0];
            timestamp = app.experiment.datarecorded(:, 4);
            
            xtarget = app.experiment.datarecorded(:, 5);
            ytarget = app.experiment.datarecorded(:, 6);
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                
                app.UITable.ColumnName = {'Saccade ID'; 'Sample ID'; 'Time (start)'; 'Duration'; 'Amplitude (x)'; 'Peak velocity'; 'Mean velocity'; 'Saccade Type'; 'Gain'; 'Accuracy'; 'Latency'};
                
                % Plot Gaze X
                plot( ...
                    app.UIAxes, ...
                    timestamp, ...
                    app.experiment.datarecorded(:, 1), ...
                    'Color', gaze_color);
                
                % Plot the center line
                yline(app.UIAxes, ...
                    app.ScreenResolutionCols.Value/2, 'Color', center_color);
                
                % Plot the Stimulus (Target)                
                plot( ...
                    app.UIAxes, ...
                    timestamp(xtarget>0), ...
                    app.experiment.datarecorded(xtarget>0, 5), '.', ...
                    'Color', target_color);
                
                % Update graph's labels
                app.UIAxes.YLabel.String = 'Gaze X (px)';
            end
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                
                app.UITable.ColumnName = {'Saccade ID'; 'Sample ID'; 'Time (start)'; 'Duration'; 'Amplitude (y)'; 'Peak velocity'; 'Mean velocity'; 'Saccade Type'; 'Gain'; 'Accuracy'; 'Latency'};
                
                % Plot Gaze Y
                plot( ...
                    app.UIAxes, ...
                    timestamp, ...
                    app.experiment.datarecorded(:, 2), ...
                    'Color', gaze_color);
                
                %Plot the center line
                yline(app.UIAxes, ...
                    app.ScreenResolutionRows.Value/2, 'Color', center_color);
                
                % Plot the Stimulus (Target)                
                plot( ...
                    app.UIAxes, ...
                    timestamp(ytarget>0), ...
                    app.experiment.datarecorded(ytarget>0, 6), '.', ...
                    'Color', target_color);
                
                % Update graph's labels
                app.UIAxes.YLabel.String = 'Gaze Y (px)';
            end
            
            app.UIAxes.Title.String = app.experiment.fname;
                        
        end
        
        function updatetable(app)
            
            add_column_type(app);
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                distColumn = 11;                
            end            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
                distColumn = 12;
            end
            
            %manually removed saccades
            app.experiment.saccadeListSelected = ...
                app.experiment.saccadeList;
            app.experiment.saccadeListSelected(app.experiment.deletedSaccades,:) = [];
            
            %apply saccadetype filtering
            if strcmp(app.SaccTypeDropDown.Value, "All") ~= 1
                if strcmp(app.SaccTypeDropDown.Value, "10") == 1
                    sacctype = 10;
                elseif strcmp(app.SaccTypeDropDown.Value, "18") == 1
                    sacctype = 18;
                elseif strcmp(app.SaccTypeDropDown.Value, "8") == 1
                    sacctype = 8;
                end
                app.experiment.saccadeListSelected = ...
                    app.experiment.saccadeListSelected(...
                        app.experiment.saccadeListSelected(:, 46) == sacctype, :);
            end
            
            % apply min amplitude
            app.experiment.saccadeListSelected = ...
                app.experiment.saccadeListSelected(...
                    app.experiment.saccadeListSelected(:, distColumn) >= app.MinAmplitudeSpinner.Value, :);
            % apply max amplitude
            app.experiment.saccadeListSelected = ...
                app.experiment.saccadeListSelected(...
                    app.experiment.saccadeListSelected(:, distColumn) <= app.MaxAmplitudeSpinner.Value, :);
            % apply min duration
            app.experiment.saccadeListSelected = ...
                app.experiment.saccadeListSelected(...
                    app.experiment.saccadeListSelected(:, 14) >= app.MinDurationSpinner.Value, :);
            % apply max duration
            app.experiment.saccadeListSelected = ...
                app.experiment.saccadeListSelected(...
                    app.experiment.saccadeListSelected(:, 14) <= app.MaxDurationSpinner.Value, :);
            % finally, update the table
            app.UITable.Data = array2table( ...
                app.experiment.saccadeListSelected(:, [2 3 45 14 distColumn 5 6 46 47 48 49]));
        end
        
        function saccade_report(app)
            
            if strcmp(app.DeviceDropDown.Value, 'EyeLink Portable Duo') == 1

                VEL_SACCADE_TH = 10; 
                SACCADE_WINDOW = 200;
                SACCADE_PCT_PEAK = 15;  
                MIN_WINDOW = 20;
                pixPerDegH = 46;
                pixPerDegV = 45;
            end
            
            saccadeList = extract_saccades(app,...
                [],...
                [],...
                1 / app.experiment.Hz * 1000,...     
                app.experiment.Hz,...            
                pixPerDegH,...          
                pixPerDegV,...                  
                VEL_SACCADE_TH,...              
                SACCADE_WINDOW,...             
                100,...                         
                SACCADE_PCT_PEAK,...            
                MIN_WINDOW); 
            
            app.experiment.saccadeList = saccadeList;
            
            
        end
    
        
        function add_column_type(app)
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                xtarget = app.experiment.datarecorded(:, 5);
                
                xtarget = xtarget(app.experiment.saccadeList(:, 3));
                              
                xtarget(xtarget == 960) = 0;
                xtarget(xtarget == 1822) = 18;
                xtarget(xtarget == 98) = 18;
                xtarget(xtarget == 1436) = 10;
                xtarget(xtarget == 484) = 10;
                
                saccade_type = xtarget;
                
            end            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
                ytarget = app.experiment.datarecorded(:, 6);
                
                ytarget = ytarget(app.experiment.saccadeList(:, 3));
                              
                ytarget(ytarget == 540) = 0;
                ytarget(ytarget == 908) = 8;
                ytarget(ytarget == 172) = 8;
                
                saccade_type = ytarget;
            end
            
            app.experiment.saccadeList = [app.experiment.saccadeList saccade_type];
        end
            
            
        function add_column_gain(app)
           
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                distColumn = 11;                
            end            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
                distColumn = 12;
            end
            
            saccade_type = app.experiment.saccadeList(:, 46);
            saccade_amplitude = app.experiment.saccadeList(:, distColumn);
            
            gain = saccade_amplitude * 0;
            gain(saccade_type==18) = saccade_amplitude(saccade_type==18) / 18;
            gain(saccade_type==10) = saccade_amplitude(saccade_type==10) / 10;
            gain(saccade_type==8) = saccade_amplitude(saccade_type==8) / 8;
            
            app.experiment.saccadeList = [app.experiment.saccadeList gain];
            
        end
        
        function add_column_accuracy(app)
                       
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                distColumn = 11;                
            end            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1            
                distColumn = 12;
            end
                   
            
            saccade_type = app.experiment.saccadeList(:, 46);
            saccade_amplitude = app.experiment.saccadeList(:, distColumn);
            
            accuracy = saccade_amplitude * 0;
            accuracy(saccade_type==18) = abs(saccade_amplitude(saccade_type==18) - 18);
            accuracy(saccade_type==10) = abs(saccade_amplitude(saccade_type==10) - 10);
            accuracy(saccade_type==8) = abs(saccade_amplitude(saccade_type==8) - 8);
            
            app.experiment.saccadeList = [app.experiment.saccadeList accuracy];
                        

        end
        
        function add_column_latency(app)
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                
                xtarget = app.experiment.datarecorded(:, 5);
                
                gaps = [0 (xtarget(2:end)~=xtarget(1:end-1))'];
                gaps_indices = find(gaps);
                timestamp = app.experiment.datarecorded(:, 4);
                gaps_times = timestamp(gaps_indices);
                
                saccade_times = app.experiment.saccadeList(:, 45);
                
                latency = saccade_times * 0;
                
                for i = 1:length(app.experiment.saccadeList(:, 1))
                    closest_gap = gaps_times(gaps_times < saccade_times(i));
                    if length(closest_gap) == 0
                        latency(i) = 0;
                    else
                        closest_gap = closest_gap(end);
                        latency(i) = saccade_times(i) - closest_gap;
                    end
                end
            end 


            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                
                ytarget = app.experiment.datarecorded(:, 6);
                
                gaps = [0 (ytarget(2:end)~=ytarget(1:end-1))'];
                gaps_indices = find(gaps);
                timestamp = app.experiment.datarecorded(:, 4);
                gaps_times = timestamp(gaps_indices);
                
                saccade_times = app.experiment.saccadeList(:, 45);
                
                latency = saccade_times * 0;
                
                for i = 1:length(app.experiment.saccadeList(:, 1))
                    closest_gap = gaps_times(gaps_times < saccade_times(i));
                    if length(closest_gap) == 0
                        latency(i) = 0;
                    else
                        closest_gap = closest_gap(end);
                        latency(i) = saccade_times(i) - closest_gap;
                    end
                end
            end 

            app.experiment.saccadeList = [app.experiment.saccadeList latency];

            
        end
    end
    
    methods (Access = public)
        
        function DATA = deblink_interp(app)

            %
            DATA = app.experiment.datarecorded_raw;
            
            % Define some minimum and maximum data expected in the
            % registration for gaze x, gaze y and pupil, respectively.
            minData = [1 1 1];
            maxData = [1920 1080 999999];            
            Km = min([size(minData, 2) size(maxData, 2)]);
            
            % Get DATA shape and add a column of ones
            [n, m] = size(DATA);
            DATA = [DATA, ones(n, 1)];

            % Initialize list of blink's point
            blinks = [];
            
            %
            last = [0 0 0];  
            blinkStatus = 0; 

            %%% BLINK DETECTION
            
            % We extend missing data ranges 
            offset = app.BlinkoffsetEditField.Value;
            
            % For each gaze point...
            for i = 1 : n
                
                % check if the values are in the valid ranges
                valid = true;                
                for j = 1 : Km                                   
                    valid = (valid) && (DATA(i, j) > minData(j)) && (DATA(i, j) <= maxData(j));
                    if ~(valid)  
                        break;                        
                    end
                end
                
                if valid 
                    
                    % If the data are all valid and the blink status is ON, it means
                    % that a blink istance just finished. Therefore blink information
                    % relative to the end index and coordinates, x and y angular 
                    % coefficients and constants defining the line of the blink istances.
                    if (blinkStatus == 1) 
                        blinkStatus = 0;
                        [nb, ~] = size(blinks); % mb 
                        blinks(nb).endIdx = min([i+offset n]); %#ok<*AGROW>
                        blinks(nb).endX = DATA(i, 1);
                        blinks(nb).endY = DATA(i, 2);
                        blinks(nb).mx = (blinks(nb).endX - blinks(nb).startX) / (blinks(nb).endIdx - blinks(nb).startIdx);
                        blinks(nb).cx = blinks(nb).endX - blinks(nb).mx * blinks(nb).endIdx;
                        blinks(nb).my = (blinks(nb).endY - blinks(nb).startY) / (blinks(nb).endIdx - blinks(nb).startIdx);
                        blinks(nb).cy = blinks(nb).endY - blinks(nb).my * blinks(nb).endIdx; 
                    end
                    
                    last = DATA(i, :); % Overwrite the last variable with the data of the current frame.
                    
                else
                    
                    % If some of the data is not valid (blink found) check if the blink
                    % status is OFF
                    if (blinkStatus == 0) 
                        blinkStatus = 1; % Switch the status of the blink to ON (the next 
                                         % frame of the same blink will not pass this control 
                        
                        % Store the information of the detected blink
                        blinks = [blinks;  struct('startIdx', max([1 i-offset]), ...
                                                  'startX', last(1),...
                                                  'startY', last(2),...
                                                  'endIdx', i, ...
                                                  'endX', last(1),...
                                                  'endY', last(2),...
                                                  'mx',0,...
                                                  'cx',0,...
                                                  'my',0,...
                                                  'cy',0)];                                                 
                    end
                end
            end
            
            [nb, ~] = size(blinks); % mb
            id = 0;
            
            %%% DEBLINKING
            
            % For each blink...
            for i = 1 : nb
                
                % For each gaze point inside the selected blink...

                for ii = blinks(i).startIdx : blinks(i).endIdx
                    
                    % If the current blink is characterized by a line 
                    % having an angular velocity or an costant different 
                    % from zero, overwrite the gaze data at the 
                    % corrisponding row index and increment the counter 
                    % taking account of the changing to the gaze data.
                    
                    if ~(blinks(i).mx == 0) || ~(blinks(i).cx == 0)
                        id = id + 1;
                        DATA(ii, 1) = ii * blinks(i).mx + blinks(i).cx; 
                        DATA(ii, m + 1) = 0;                    
                    end
                    
                    if ~(blinks(i).my == 0) || ~(blinks(i).cy == 0)
                        id = id + 1;
                        DATA(ii, 2) = ii * blinks(i).my + blinks(i).cy; 
                        DATA(ii, m + 1) = 0;                    
                    end
                end
            end
            
            %%% FILTERING GAZE DATA (butterworth filter)
            DATA(:, 1) = filter_butterworth(app, DATA(:, 1)); 
            DATA(:, 2) = filter_butterworth(app, DATA(:, 2)); 
            
            datax = DATA(:, 1);
            datay = DATA(:, 2);
            
            datax = [datax(3 + 1 : length(datax), :); zeros(3, 1)]; 
            datay = [datay(3 + 1 : length(datay), :); zeros(3, 1)]; 
            
            DATA(:, 1) = datax;
            DATA(:, 2) = datay;   

        end
        
        function results = filter_butterworth(app, dataIn)
                
            n = 3; % Assign to the variable n the order of the butterworth filter
            WNiquist = app.experiment.Hz/ 2; % Define the analysis Nyquist pulsation
            Wn = 25 / WNiquist; % Define the cut pulsation          
            ftype = 'low';  % Define the filter type
            [z, p, k] = butter(n, Wn, ftype); % Compute the butterworth filter parameters
            [sos, g] = zp2sos(z, p, k); % Find the zero poles for the filter
            Hd = dfilt.df2sos(sos, g);  % Create the butterworth filter with the parameters previously defined
            results = filter(Hd, dataIn); % Filter the input data 

        end
        
        function dataOut = filter_butterworth_vel(app, dataIn)
 
            % dataOut = filter_butterworth(dataIn) filter the input data through a
            % butterworth filter.
            % 
            % dataIn       doubles     Nx7     data to be filtered
            %
            % dataOut      doubles     Nx7     filtered data
            % 
            % Author:      Pamela Federighi
            % Time-stamp:  2011-02-13 
            % E-mail:      federighi@unisi.it
            % Comments made by Valeria Serchi Mar 2016
            
            n = 3; % Assign to the variable n the order of the butterworth filter
            WNiquist = app.experiment.Hz / 2; % Define the analysis Nyquist pulsation
            Wn = 40 / WNiquist; % Define the cut pulsation          
            ftype = 'low';  % Define the filter type
            [z, p, k] = butter(n, Wn, ftype); % Compute the butterworth filter parameters
            [sos, g] = zp2sos(z, p, k); % Find the zero poles for the filter
            Hd = dfilt.df2sos(sos, g);  % Create the butterworth filter with the parameters previously defined
            % h = fvtool(Hd);	% Launch the filter visualization tool              
            % set(h, 'Analysis', 'freq') % Set the axis of the filter visualization tool
            
            dataOut = filter(Hd, dataIn); % Filter the input data
        end

        function vectorOut = filter_threshold_vel(app, vectorIn, rifThreshold)
         
            % vectorOut = filter_threshold_vel(vectorIn, rifThreshold) return a vector
            % equal to the input signal except for the indexes found to have values >=
            % or <0 to the rifThreshol variable (that are set to zero).
            % 
            % vectorIn      dobles      Nx1  gaze vector 
            % 
            % rifThreshold  doubles     1x1   velocity threshold
            % 
            % vectorOut     doubles     Nx1   filtered gaze vector
            %                                
            % 
            % Author:      Pamela Federighi
            % Time-stamp:  2011-02-13 
            % E-mail:      federighi@unisi.it
            % Comments made by Valeria Serchi Mar 2016
            
            x = vectorIn;         % Assign to the variable x the input gaze vector
            rif = rifThreshold;   % Assign to the variable rif the vale of the input variable rifThreshold
            [n, ~] = size(x);     % Assign to the variable n the number of the columns of the gaze vector
            y = zeros(n, 1);      % Initialize the vector y to a vector of zeros having the same size of 
                                  % the input gaze vector
            
            % Make a loop on the rows of the input gaze vector. If a value within +-rif 
            % is found, it is assigned to the corresponding row of the vector y  
            for i = 1 : n
                if x(i) >= rif || x(i) <= -rif
                    y(i) = x(i);
            
                end
            end

            vectorOut = y; % Assign to the output variable vectorOut the value of the variable y.
        end
        
        function outmat = risampledata(inmat, freq)
        
            % outmat = risampledata(inmat, freq) 
            % 
            % inmat   doubles Nx7  x-, y- and z-coordinates and time-stamp of two synced signals 
            %                      to be resampled. 
            %  
            % freq    doubles 1x1  new sample frequency the input signal has to be resampled at. 
            % 
            % outmat  doubles Nx4  x-, y- and z-coordinates and time-stamp of a signal 
            %                      to be resampled.   
            % 
            % Author:      Pamela Federighi
            % Time-stamp:  2011-02-13 
            % E-mail:      federighi@unisi.it
            % Comments made by Valeria Serchi Mar 2016
            toberemoved = [];
            for i=2:length(inmat(:, 4))
                if inmat(i, 4) == inmat(i-1, 4)
                    toberemoved = [toberemoved i];
                end
            end
            inmat(toberemoved, :) = [];
            
            t = inmat(:, 4);                % Assign the time-stamp of the signals to the variable t [ms]
            passot = (1 / freq) * 1000;     % Assign to the variable passot the time step [ms]
            st = min(t);                    % Assign to the variable st the time step of the input signals [ms]
            en = max(t) + passot;           % Assign to the variable en the maximum value of the time-stamp of 
                                            % the new time vector (maximum value of the time of the signals plus 
                                            % the passot)
            tnew = (st : passot : en)';     % Assign to the variable tnew the time vector for the resampled signals
            
            % Resample the input signals using the new time-stamp vector
            disp(tnew);
            y1 = interp1(t,inmat(:,1),tnew,'linear','extrap'); 
            y2 = interp1(t,inmat(:,2),tnew,'linear','extrap'); 
            y3 = interp1(t,inmat(:,3),tnew,'linear','extrap'); 
            
            y5 = interp1(t,inmat(:,5),tnew,'nearest','extrap'); 
            y6 = interp1(t,inmat(:,6),tnew,'nearest','extrap'); 
            y7 = interp1(t,inmat(:,7),tnew,'nearest','extrap'); 
            
            
            outmat=[y1, y2, y3, tnew, y5, y6, y7];
        end
        
        function saccadeList = extract_saccades(app,...
            index,...
            ROI,... 
            acqIntvl,...            
            acqRate,...             
            pixPerDegH,...          
            pixPerDegV,...          
            saccadeVelThresh,...    
            saccadeWindow,...       
            saccadeMinFixDuration,... 
            saccadePctPeak,...      
            saccadeMinDuration)    %#ok<INUSL>
        
        % saccadeList = extract_saccades(data, index, ROI, acqIntvl, acqRate, pixPerDegH, pixPerDegV, saccadeVelThresh, saccadeWindow,
        % saccadeMinFixDuration, saccadePctPeak, saccadeMinDuration) find the variations in the position of the stimulus on the screen.
        % 
        % data                      doubles     Nx7     analysis data organized as x-coord and of the 
        %                                               gaze
        %
        % index                     doubles     1x1     threshold of variation (over this threshold the script
        %                                               detect a variation in the position of the stimulus)
        % 
        % ROI                    
        % 
        % acqIntvl                  doubles     1x1     acquisition interval value
        % 
        % acqRate                   doubles     1x1     acquisition rate
        % 
        % pixPerDegH                doubles     1x1     horizontal ratio px/deg 
        % 
        % pixPerDegV                doubles     1x1     vertical ratio px/deg 
        % 
        % saccadeVelThresh          doubles     1x1     velocity threshold for the saccades identification
        % 
        % saccadeWindow                         1x1     not used in this version of the function
        % 
        % saccadeMinFixDuration     doubles     1x1     minimum duration 
        % 
        % saccadePctPeak            doubles     1x1     threshold of variation (over this threshold the script
        %                                               detect a variation in the position of the stimulus)
        % 
        % saccadeMinDuration        doubles     1x1     minimum duration threshold for saccades identification
        %
        % saccadeList                   [{}]    Mx1     information about the saccades recognized to be occurring
        %                                               during the analysis
        % 
        % Author:      Pamela Federighi (modified by Dario Zanca)
        % Time-stamp:  2011-02-13 
        % E-mail:      federighi@unisi.it
        % Comments made by Valeria Serchi Mar 2016
        
        data = app.experiment.datarecorded;
        
        if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                
            saccadeList = []; % Initialize the variable saccadeList to an empty array
            
            % If the variable index is empty 
            if isempty(index)
                index = [1 size(data, 1)];
            
            end
                
            isFixROI = ~isempty(ROI);   % Assign the logic not of the isempty(ROI) to the variable isFixROI 
            vCrit = saccadeVelThresh;   % Assign to the variable vCrit the value of the threshold for the saccades detection
            trials = size(index, 1);    % Initialize the variable trials to the rows number of the variable index  
            fLen = round(saccadeMinFixDuration / acqIntvl); % Assign the mimimum lenght to detect a fixation to the variable 
                                                            % fLen computed as the ratio between the input threshold and the 
                                                            % acquisition time step
            
            % Get the data of the gaze from the input data variable
            for i = 1 : trials
            %     sprintf('update %f Calculating Trial %i', 100 * i / trials, i) % Print out he number of the trial being processed
                t1 = index(i, 1); % Assign to the variable t1 the number of rows of the input matrix data
                t2 = index(i, 2); % Assign to the variable t2 the number of columns of the input matrix data
                trialx = []; % Initialize to an empty vector the variable trialx
                trialy = []; % Initialize to an empty vector the variable trialy
                 
                for ii = t1 : t2
                    
                    % Check if the variable data is a structure. If so, the gaze data 
                    % is stored in the rows of tha variable data corresponding to the 
                    % ii-th trial and to its subfields x and y.If not, it means that
                    % the variable data stores the values relative to only one trial
                    % and the x- and y-coordinates of the gaze are stored in the first
                    % and second columns of the input matrix data. 
                    if (isstruct(data(ii)))
                        trialx = [trialx; data(ii).x]; %#ok<AGROW>
                        trialy = [trialy; data(ii).y]; %#ok<AGROW>
                    
                    else
                        trialx = [trialx; data(ii,1)]; %#ok<AGROW>
                        trialy = [trialy; data(ii,2)]; %#ok<AGROW>
                    
                    end
                end
               
                tT = 1;  % Initialize the variable tT to the value 1
                
                if isFixROI      
                    g1 = tT;
                    g2 = tT + fLen - 1;         
                    
                    if g2 > t2 
                        g2 = t2; 
                    
                    end
                    
                    g = find(isfinite(trialx(g1 : g2)));  
                    inROI = is_in_ROI([trialx(g) trialy(g)], ROI);
                    
                else
                    inROI = 1;
                    
                end
                 
                if inROI
            %  --------------------------------------------------------         
            %  Find the absolute velocity vector (ILAB pixels/sample),
            %  ALGORITMO a 1 punto
            %  --------------------------------------------------------         
            %         vx = [0; diff(trialx)];
            %         vy = [0; diff(trialy)];
            %         ax = [0; diff(vx)];
            %         ay = [0; diff(vy)];
            %        
            %         vabs = sqrt(vx.^2 + vy.^2);    %pixel
            %         aabs = sqrt(ax.^2 + ay.^2);    %pixel
            % 
            %
            %      % oppure, calcolo in degree, consderando assi orizzontale e verticale diversi: 
            %         vxt=(vx/pixPerDegH)*acqRate        %degree
            %         vyt=(vy/pixPerDegV)*acqRate        %degree
            %         
            %         vabs = sqrt(vxt.^2 + vyt.^2);      %degree
            %         aabs = sqrt(ax.^2 + ay.^2);        %pixel       
                    
                    a = [0.8024; -0.2022; 0.03904; -0.003732]; % Define the parameters for the 4 points velocity computation
                    vx = [0; diff(trialx)];% Compute a 1-point velocity along the x-coordinate
            % %         vxrif = vx;
                    vxs= zeros(-5 + t2 - 4, 1); % Initialize to an empty vector the variable vxs
                            
                    % Computation of the 5-points x-velocity   
                    for indDatox = 5 : t2 - 4 % Make a loop over the rows 
                        vx(indDatox, 1) = 0;
                     
                        for nx = 1 : 4    
                          vxs(indDatox, 1) = a(nx, 1) * (trialx(indDatox + nx) - trialx(indDatox - nx));
                          vx(indDatox, 1) = vx(indDatox, 1) + vxs(indDatox, 1);
                        
                        end
                    end
                    
                    vy = [0; diff(trialy)];% Compute a 1-point velocity along the x-coordinate
            % %         vyrif = vy;
                     vys = zeros(-5 + t2 - 4, 1); %%[] % Initialize to an empty vector the variable vys
            
                    % Computation of the 5-points y-velocity
                    for indDatoy = 5 : t2 - 4
                        vy(indDatoy, 1) =0 ;
                        
                        for ny = 1 : 4    
                              vys(indDatoy, 1) = a(ny, 1) * (trialy(indDatoy + ny) - trialy(indDatoy - ny));
                              vy(indDatoy, 1) = vy(indDatoy, 1) + vys(indDatoy, 1);
            
                        end
                    end
            
                    vx = filter_butterworth_vel(app, vx); % Filter the x-velocity vector through a butterworth filter
                    vy = filter_butterworth_vel(app, vy); % Filter the y-velocity vector through a butterworth filter
                    
                    vx = [vx(2 + 1 : length(vx), :); zeros(2, 1)]; % Cut the first two samples of the variable vx
                    vy = [vy(2 + 1 : length(vy), :); zeros(2, 1)]; % Cut the first two samples of the variable vy
                     
                    ax = [0; diff(vx)]; % Assign to the variable ax the acceleration of the signal along the x direction %pixel
                    ay = [0; diff(vy)]; % Assign to the variable ay the acceleration of the signal along the y direction %pixel
              
                    ax1 = [0; diff(vx)]; %px
                    ay1 = [0; diff(vy)]; %px
            %         
                    vx = (vx * acqRate) / pixPerDegH; % Convert the vx from px to degree of visual angles
                    vy = (vy * acqRate) / pixPerDegV; % Convert the vy from px to degree of visual angles       
                           
            %         ax1 = [0; diff(vx)]; %degree
            %         ay1 = [0; diff(vy)]; %degree
                    
                    vabs = sqrt(vx .^ 2 + vy .^ 2); % Compute the absolute velocity of the signal     %degree
                    aabs = sqrt(ax .^ 2 + ay .^ 2); % Compute the absolute acceleration of the signal %pixel
                    aabs1 = sqrt(ax1 .^ 2 + ay1 .^ 2); % Compute the absolute acceleration of the signal %degree
                    
                    vxabs = sqrt(vx .^ 2); % Compute the absolute value of the x-velocity      %degree     
                    vyabs = sqrt(vy .^ 2); % Compute the absolute value of the y-velocity      %degree     
                
                    axabs = sqrt(ax .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %pixel     
                    ayabs = sqrt(ay .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %pixel    
                 
                    axabs1 = sqrt(ax1 .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %degree     
                    ayabs1 = sqrt(ay1 .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %degree    
                 
                    vCutoff = vCrit;   %#ok<NASGU>
                    vCritx = vCrit; % Assign to the variable vCritx the critical value 
                                    % of velocity used to detect a saccade
                    
                    vCrity = vCrit; % Assign to the variable vCrity the critical value 
                                    % of velocity used to detect a saccade
                    
                    vxthreshold = [];%#ok<NASGU> 
                    vythreshold = [];%#ok<NASGU> 
                    
                    % Call the function filter_threshold_vel to isolate the part of the
                    % signal with a velocity within the range of saccade (vCritx)
                    vxthreshold = filter_threshold_vel(app, vx, vCritx); % Call for the x-velocity threshold filter. Return a vector with null 
                                           % elements where the velocity of the signal is not within the range 
                                           % specified (vCritx). 
                              
                    vythreshold=filter_threshold_vel(app, vy, vCrity); % Call for the y-velocity threshold filter. Return a vector with null 
                                           % elements where the velocity of the signal is not within the range 
                                           % specified (vCrity). 
                  
                    mvxthreshold = sqrt(vxthreshold.^2); %#ok<NASGU> % Normalize the velocity threshold vector
                    mvythreshold = sqrt(vythreshold.^2); %#ok<NASGU> % Normalize the velocity threshold vector
                    
            % Plot the velocity vector (x)
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vx));   % Define the temporal vector for the gaze [ms]       % range of vabs in ms.      
            %                plot(vRange/1000, vx, 'k'); % plot in pixel per secondi              
            %                hold on;
            %                plot(acqIntvl*[1 length(vx)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
            % 
            % Plot the normalized velocity vector (x)
            %                figure;
            %                hold on;
            %                vRange = acqIntvl*(1:length(vxabs));  % Define the temporal vector for the gaze [ms]       % range of vabs in ms.      
            %                plot(vRange/1000, vxabs, 'k');   % plot in pixel per secondi           
            %                hold on;
            %                plot(acqIntvl*[1 length(vxabs)]./1000, [ vCrit vCrit], 'b--');
            %                drawnow;
            %                pause(1);
             
            
                    % Find the critical index to identify the saccades        
                    iCrit = find(vxthreshold ~= 0); % Assign to the variable iCrit the 
                                                    % index of all the elements of vxthreshold 
                                                    % different from zero (saccades instances).
                   
                    % If elements equal to zero were found go on with the following
                    % computation
                    if ~isempty(iCrit) % If there are saccades
                        k = 1;  % Initialize the counter of the following while loop
                        scnt = 0;  % Initialize a counter to take count of the saccades number
                        s2 = 1;  
                        
                        while k <= length(iCrit)-3   %
                            
                           % Check if there are three consecutive indexes having a velocity within the threshold
                           if (iCrit(k) + 1 == iCrit(k + 1)) && (iCrit(k + 1) + 1 == iCrit(k + 2)) && (iCrit(k + 2) + 1 == iCrit(k + 3)) %& (iCrit(k+3)+1==iCrit(k+4)) & (iCrit(k+4)+1==iCrit(k+5))& (iCrit(k+5)+1==iCrit(k+6))& (iCrit(k+6)+1==iCrit(k+7)) & (iCrit(k+7)+1==iCrit(k+8))& (iCrit(k+8)+1==iCrit(k+9)) & (iCrit(k+9)+1==iCrit(k+10))    %condition if tre ara 10 index witn velocity > vCrit 
                               i1 = iCrit(k);   % Assign to i1 the index of the current saccade   
                               valuevx = sign(vxthreshold(i1)); % Assign to valuevx the sign of the elements at index i1
                               i2c = min(find(vxthreshold(i1 : end) == 0)); %#ok<MXFND> % Find the next index with a zero element 
                               i2c2 = min(find(sign(vxthreshold(i1 : end)) ~= valuevx)); %#ok<MXFND> % Find the next index with a sign of the velocity opposite 
                                                                                                     % to the velocity of the signal at the index i1
                               i2 = i1 + i2c - 2; % Define the final index where the vxthreshold goes back to zero
                               i2bis = i1 + i2c2 - 2; % Define the final index where the vxthreshold changes in the sign
                               i2 = min(i2, i2bis); % Overwrite i2 with the minimum index between the next change in sign 
                                                    % and the zeros in the vector vxthreshold.
                                                    
                               i2 = min(i2, length(vabs)); % If the index is longer than the length of the velocity vector, 
                                                           % overwrite it with the last index of th vector vabs.
                                
                               %  i2 = min(i2bis, length(vabs)); 
                             
                               vPeak = max(vabs(i1 : i2)); % Find the peak of the absolute velocity between the i1 and i2
                               
                               vxPeak = max(vxabs(i1 : i2)); % Find the peak of the x-velocity between the i1 and i2 
                               
                               vyPeak = max(vyabs(i1 : i2)); % Find the peak of the y-velocity between the i1 and i2
              
                               aPeak = [min(aabs(i1 : i2)), max(aabs(i1 : i2))]; %#ok<NASGU> % Find the peak of the absolute acceleration between the i1 and i2 px
                               
                               aPeak1 = [min(aabs1(i1 : i2)), max(aabs1(i1 : i2))]; % Find the peak of the absolute acceleration between the i1 and i2 deg
                               
                               axPeak = [min(ax(i1 : i2)), max(ax(i1 : i2))]; %#ok<NASGU> % Find the peak of the x-acceleration between the i1 and i2 px
                               
                               ayPeak = [min(ay(i1 : i2)), max(ay(i1 : i2))]; %#ok<NASGU> % Find the peak of the y-acceleration between the i1 and i2 px
                              
                               axPeak1 = [min(ax1(i1 : i2)), max(ax1(i1 : i2))]; % Find the peak of the x-acceleration between the i1 and i2 deg
                               
                               ayPeak1 = [min(ay1(i1 : i2)), max(ay1(i1 : i2))]; % Find the peak of the y-acceleration between the i1 and i2 deg
                               
                               iPeak = i1 + min(find(vabs(i1 : i2) == vPeak)) - 1;   %#ok<MXFND> % Find the index of the peak of the absolute 
                                                                                                 % velocity between the i1 and i2 
                                                                                                 
                               ixPeak = i1 + min(find(vxabs(i1 : i2) == vxPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                                  % x-velocity between the i1 and i2
                                                                                                  
                               iyPeak = i1 + min(find(vyabs(i1 : i2) == vyPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                                  % y-velocity between the i1 and i2
                                                                                                  
                               s1 = i1;  % Assign to s1 the index of start of the current saccade (i1) 
                              
                               % Assign to s2 the ending index of the current saccade (i2)
                               if isempty(s2)
                                    s2 = length(vabs);
                                
                               else
                                    s2 = i2;
                                
                               end
                            
                               % Compute the time in ms corresponding to the starting
                               % and ending indexes of the current saccades
                               ts1 = s1 * acqIntvl;  % [ms]
                               ts2 = s2 * acqIntvl;  % [ms]
                               
                               %sprintf('ts2,ts1, saccadeMinDuration %i %i %i',ts2,ts1, saccadeMinDuration)
                                
                               % Check if the current saccade is a saccade. Check the
                               % duration of the signal within the starting and ending
                               % indexes of the current saccade.                
                            if ((ts2 - ts1) > saccadeMinDuration)
            
                                    iNaN = find(~isfinite(vabs(s1 : s2))); % Find the NaN elements of the signal 
                                                                           % within the times s1 and s2.
            
                                    pctInvalid = (length(iNaN)/(s2 - s1 + 1)) * 100;  % Compute the percentage of the NaN 
                                                                                      % values of the vector within the times 
                                                                                      % s1 and s2.
            
                                    iV = find(isfinite(vabs(s1:s2))); % Find the indexes of the finite elements of the signal 
                                                                      % within the times s1 and s2.
                                    iV = s1 + iV - 1; % Find the indexes of the finite elements of the signal 
                                                      % within the times s1 and s2 with respect to the entire 
                                                      % length of the gaze signal	       
                                   
                                    % Find the mean absolute velocity and acceleration
                                    % of the current saccade.   
                                    if ~isempty(iV)
                                       vMean = mean(vabs(iV));            
                                       aMean = mean(aabs(iV));             
            
                                    else
                                       % If no finite elements are found the mean
                                       % absolute velocity and acceleration are set to
                                       % NaN values.
                                       vMean = NaN;
                                       aMean = NaN;
            
                                    end
            
                                iNaNx = find(~isfinite(vxabs(s1 : s2))); % Find the NaN elements of the x-velocity 
                                                                         % within the times s1 and s2
                                
                                pctInvalidx = (length(iNaNx)/(s2 - s1 + 1)) * 100;    % Compute the percentage of the NaN 
                                                                                      % values of the x-velocity within the times 
                                                                                      % s1 and s2.
            
                                iVx = find(isfinite(vxabs(s1:s2)));  % Find the indexes of the finite elements of the signal 
                                                                     % within the times s1 and s2.
                                iVx = s1 + iVx - 1;  % Find the indexes of the finite elements of the x-velocity 
                                                     % within the times s1 and s2 with respect to the entire 
                                                     % length of the gaze signal          
                                % Find the mean absolute x-velocity and x-acceleration
                                % of the current saccade. 	       
                                if ~isempty(iVx)
                                     vxMean = mean(vxabs(iVx)); 
                                     axMean = mean(ax1(iVx));             
                                else
                                     vxMean = NaN;
                                     axMean = NaN;
                                     
                                end
            
                               iNaNy = find(~isfinite(vyabs(s1 : s2))); % Find the NaN elements of the y-velocity 
                                                                            % within the times s1 and s2
            
                               pctInvalidy = (length(iNaNy) / (s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                                    % values of the y-velocity within the times 
                                                                                    % s1 and s2.                    
                               iVy = find(isfinite(vyabs(s1 : s2))); % Find the indexes of the finite elements of the y-velocity 
                                                                   % within the times s1 and s2.
            
                               iVy = s1 + iVy - 1;	% Find the indexes of the finite elements of the y-velocity 
                                                    % within the times s1 and s2 with respect to the entire 
                                                    % length of the gaze signal        
                               
                               % Find the mean absolute y-velocity and y-acceleration
                               % of the current saccade. 
                               if ~isempty(iVy)
                                   vyMean = mean(vyabs(iVy)); 
                                   ayMean = mean(ay1(iVy));             
            
                               else
                                   vyMean = NaN;
                                   ayMean = NaN;
            
                               end
                                 
                            sRT = (s1-tT+1)* acqIntvl; % Find the starting time of the saccade         
                            ttP = (iPeak-tT+1)* acqIntvl; % Find the time of the peak of the absolute velocity        
                            ttPx = (ixPeak-tT+1)* acqIntvl; %#ok<NASGU> % Find the time of the peak of the x-velocity  
                            ttPy = (iyPeak-tT+1)* acqIntvl; %#ok<NASGU> % Find the time of the peak of the y-velocity
            
                             %vPeak = vPeak*acqRate/pixPerDegH; % Convert the peak velocity to deg*s   
                             %vMean = vMean*acqRate/pixPerDegH; % Convert the mean velocity to deg*s    
            %                  vPeak = vPeak;   % 
            %                  vMean = vMean;   % 
            %                     
            %                  vxPeak = vxPeak;   % 
            %                  vxMean = vxMean;   % 
            %                     
            %                  vyPeak = vyPeak;   % 
            %                  vyMean = vyMean;   % 
                              
                             aPeak1 = aPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the absolute acceleration between the i1 and i2 deg
                             axPeak1 = axPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the x-acceleration between the i1 and i2 deg
                             ayPeak1 = ayPeak1 * (acqRate * acqRate) / (pixPerDegV); % Find the peak of the x-acceleration between the i1 and i2 deg
            
                             aMean = aMean * (acqRate * acqRate) / (pixPerDegH);  % Convert the mean acceleration to deg*s^2 
                             axMean = axMean * (acqRate * acqRate) / (pixPerDegH); % Convert the x mean acceleration to deg*s^2  
                             ayMean = ayMean * (acqRate * acqRate) / (pixPerDegV);  % Convert the y mean acceleration to deg*s^2
                                
                             dSac = vMean * (ts2 - ts1) / 1000; % Compute the saccadic duration     
                             dSacx = vxMean * (ts2 - ts1) / 1000;  % Compute the x saccadic duration   
                             dSacy = vyMean * (ts2 - ts1) / 1000;  % Compute the y saccadic duration   
                                
                             x1 = trialx(s1);  % Compute the saccade x coordinate corrisponding to the start of the current saccade 
                             x2 = trialx(s2);  % Compute the saccade x coordinate corrisponding to the end of the current saccade 
                             y1 = trialy(s1);  % Compute the saccade y coordinate corrisponding to the start of the current saccade
                             y2 = trialy(s2);  % Compute the saccade y coordinate corrisponding to the end of the current saccade 
                             distx = (x2 - x1) / pixPerDegH;  % Compute the x saccadic distance (Pitagora)              
                             disty = (y2 - y1) / pixPerDegV; % Compute the y saccadic distance (Pitagora)
                             distSac = sqrt(distx ^ 2 + disty ^ 2); % Compute the absolute saccadic distance (Pitagora)
                                    
                             distSacx = abs(x2 - x1) / pixPerDegH;  % Convert the x saccadic distance to degree of visual angle (Pitagora)   
                             distSacy = abs(y2 - y1) / pixPerDegV;  % Convert the y saccadic distance to degree of visual angle (Pitagora)   
            
                            % Compute the distance between the minimum and the
                            % miminum value and the starting coordinates of the
                            % saccade. (Same steps as the previos ones)
                              iNaNtx = find(~isfinite(trialx(s1:s2)));
                              pctInvalidtx=(length(iNaNtx)/(s2-s1+1))*100; 
                                
                              iVtx = find(isfinite(trialx(s1:s2)));
                              iVtx = s1 + iVtx - 1;	       
                              if ~isempty(iVtx)
                                  distxPeakMax = max(trialx(iVtx));        
                                  distxPeakMin = min(trialx(iVtx)); 
                                  distxPeakRifMax = abs(x1-distxPeakMax)/pixPerDegH;
                                  distxPeakRifMin = abs(x1-distxPeakMin)/pixPerDegH;
                                  idistxPeakMax = s1 + max(find(trialx(s1:s2) == distxPeakMax)) - 1;   %#ok<MXFND>
                                  idistxPeakMin = s1 + max(find(trialx(s1:s2) == distxPeakMin)) - 1;   %#ok<MXFND>
                                  
                              else
                                  distxPeakMax = NaN; %#ok<NASGU>
                                  distxPeakMax = NaN; %#ok<NASGU>
                                  distxPeakRifMax = NaN; 
                                  distxPeakRifMin = NaN; 
                                  idistxPeakMax = NaN;
                                  idistxPeakMin = NaN;
                                  
                              end
                                             
                              iNaNty = find(~isfinite(trialy(s1:s2)));
                              pctInvalidty=(length(iNaNty)/(s2-s1+1))*100; 
                                
                              iVty = find(isfinite(trialy(s1:s2)));
                              iVty = s1 + iVty - 1;	       
                              if ~isempty(iVty)
                                 distyPeakMax = max(trialy(iVty));       
                                 distyPeakMin = min(trialy(iVty));       
                                 distyPeakRifMax = abs(y1-distyPeakMax)/pixPerDegV;
                                 distyPeakRifMin = abs(y1-distyPeakMin)/pixPerDegV;
                                 idistyPeakMax = s1 + max(find(trialy(s1:s2) == distyPeakMax)) - 1;   %#ok<MXFND>
                                 idistyPeakMin = s1 + max(find(trialy(s1:s2) == distyPeakMin)) - 1;   %#ok<MXFND>
                              
                              else
                                 distyPeakMax = NaN; %#ok<NASGU>
                                 distyPeakMin = NaN; %#ok<NASGU>
                                 distyPeakRifMax = NaN; 
                                 distyPeakRifMin = NaN; 
                                 idistyPeakMax = NaN;  
                                 idistyPeakMin = NaN;
                              
                              end
                                                  
                              durat = (ts2 - ts1); % Get the saccade duration
                              scnt = scnt + 1; % Increment the saccade counter
                                
            
                              saccadeList = [saccadeList; i scnt s1 s2 vPeak vMean ... %6
                                             sRT ttP dSac distSac distSacx distSacy ... %12
                                             pctInvalid durat dSacx dSacy vxPeak vxMean ... %18
                                             vyPeak vyMean aMean pctInvalidx pctInvalidy ... %23
                                             pctInvalidtx pctInvalidty distxPeakRifMax ... %26
                                             distxPeakRifMin distyPeakRifMax distyPeakRifMin ... %29
                                             idistxPeakMax idistxPeakMin idistyPeakMax ... %32
                                             idistyPeakMin t1 t2 aPeak1 aMean axPeak1 axMean ... %41 (aPeak1 and axPeak1 are couples)
                                             ayPeak1 ayMean app.experiment.datarecorded(s1,4)]; %45 (ayPeak1 is a couple)
                                
                              k = min(find(iCrit > s2)); %#ok<MXFND> % Get the next index for the saccade reaserch 
                                                                     % (next index of the vector iCrit different from zero).
                            else
                              k = k + 1;     % If the duration of the signal within the starting 
                                             % and ending indexes of the current saccade is less 
                                             % than the duration threshold, skip the current saccade 
                                             % because it is not a asaccade.          
                            end
                            
                           else
                               k = k + 1; % If there are not at least three consecutive elements belonging 
                                          % to the current saccades is discarded.
                           end  
                        end  
                    end  
                end   
        end   
    end
    
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                saccadeList = []; % Initialize the variable saccadeList to an empty array

% If the variable index is empty 
if isempty(index)
    index = [1 size(data, 1)];

end

isFixROI = ~isempty(ROI);   % Assign the logic not of the isempty(ROI) to the variable isFixROI 
vCrit = saccadeVelThresh;   % Assign to the variable vCrit the value of the threshold for the saccades detection
trials = size(index, 1);    % Initialize the variable trials to the rows number of the variable index  
fLen = round(saccadeMinFixDuration / acqIntvl); % Assign the mimimum lenght to detect a fixation to the variable 
                                                % fLen computed as the ratio between the input threshold and the 
                                                % acquisition time step

% Get the data of the gaze from the input data variable
for i = 1 : trials
%     sprintf('update %f Calculating Trial %i', 100 * i / trials, i) % Print out he number of the trial being processed
    t1 = index(i, 1); % Assign to the variable t1 the number of rows of the input matrix data
    t2 = index(i, 2); % Assign to the variable t2 the number of columns of the input matrix data
    trialx = []; % Initialize to an empty vector the variable trialx
    trialy = []; % Initialize to an empty vector the variable trialy
    
    for ii = t1 : t2
        
     % Check if the variable data is a structure. If so, the gaze data 
        % is stored in the rows of tha variable data corresponding to the 
        % ii-th trial and to its subfields x and y.If not, it means that
        % the variable data stores the values relative to only one trial
        % and the x- and y-coordinates of the gaze are stored in the first
        % and second columns of the input matrix data. 
        if (isstruct(data(ii)))
            trialx = [trialx; data(ii).x]; %#ok<AGROW>
            trialy = [trialy; data(ii).y]; %#ok<AGROW>
        
        else
            trialx = [trialx; data(ii,1)]; %#ok<AGROW>
            trialy = [trialy; data(ii,2)]; %#ok<AGROW>
            
        end
    end
    
    tT = 1; % Initialize the variable tT to the value 1
   
    if isFixROI
        g1 = tT;
        g2 = tT + fLen - 1;         
                                  
        if g2 > t2 
            g2 = t2; 
        
        end        
        
        g = find(isfinite(trialx(g1 : g2)));  
        inROI = is_in_ROI([trialx(g) trialy(g)], ROI);
        
    else
        inROI = 1;
        
    end
    
    
    if inROI
        
        %  --------------------------------------------------------         
        %  Find the absolute velocity vector (ILAB pixels/sample),
        %  ALGORITMO a 1 punto
        %  --------------------------------------------------------         
%         vx = [0; diff(trialx)];
%         vy = [0; diff(trialy)];
%         ax = [0; diff(vx)];
%         ay = [0; diff(vy)];
%        
%         vabs = sqrt(vx.^2 + vy.^2);    %pixel
%         aabs = sqrt(ax.^2 + ay.^2);    %pixel
% 
%
%      % oppure, calcolo in degree, consderando assi orizzontale e verticale diversi: 
%         vxt=(vx/pixPerDegH)*acqRate        %degree
%         vyt=(vy/pixPerDegV)*acqRate        %degree
%         
%         vabs = sqrt(vxt.^2 + vyt.^2);      %degree
%         aabs = sqrt(ax.^2 + ay.^2);        %pixel
%         
       
        a = [0.8024; -0.2022; 0.03904; -0.003732]; % Define the parameters for the 4 points velocity computation
        vx = [0; diff(trialx)]; % Compute a 1-point velocity along the x-coordinate
% %         vxrif = vx;
        vxs = zeros(-5 + t2 - 4, 1); %%[] % Initialize to an empty vector the variable vxs
        
        % Computation of the 5-points x-velocity
        for indDatox = 5 : t2 - 4 % Make a loop over the rows 
             vx(indDatox, 1) = 0;
             
             for nx = 1 : 4        
                  vxs(indDatox, 1) = a(nx, 1) * (trialx(indDatox + nx) - trialx(indDatox - nx));
                  vx(indDatox, 1) = vx(indDatox, 1) + vxs(indDatox, 1);
             
             end
        end
        
        vy = [0; diff(trialy)]; % Compute a 1-point velocity along the x-coordinate
% %         vyrif = vy;
        vys = zeros(-5 + t2 - 4, 1); %%[] % Initialize to an empty vector the variable vys
        
        % Computation of the 5-points y-velocity
        for indDatoy = 5 : t2 - 4
             vy(indDatoy, 1) = 0;

             for ny = 1 : 4    
                      vys(indDatoy, 1) = a(ny, 1) * (trialy(indDatoy + ny) - trialy(indDatoy - ny));
                      vy(indDatoy, 1) = vy(indDatoy, 1)+vys(indDatoy, 1);

             end
        end


        vx = filter_butterworth_vel(vx);  % Filter the x-velocity vector through a butterworth filter
        vy = filter_butterworth_vel(vy);  % Filter the y-velocity vector through a butterworth filter
      
        vx = [vx(2 + 1 : length(vx), :); zeros(2, 1)]; % Cut the first two samples of the variable vx
        vy = [vy(2 + 1 : length(vy), :); zeros(2, 1)]; % Cut the first two samples of the variable vy
                
        ax = [0; diff(vx)]; % Assign to the variable ax the acceleration of the signal along the x direction
        ay = [0; diff(vy)];    
 % Assign to the variable ay the acceleration of the signal along the y direction
          
%        vabs = sqrt(vx.^2 + vy.^2);    %pixel
%        aabs = sqrt(ax.^2 + ay.^2);    %pixel
         
%       %oppure   

        ax1 = [0; diff(vx)]; %px
        ay1 = [0; diff(vy)]; %px
        
        vx = (vx * acqRate) / pixPerDegH; % Convert the vx from px to degree of visual angles
        vy = (vy * acqRate) / pixPerDegV; % Convert the vy from px to degree of visual angles
        
        vabs = sqrt(vx .^ 2 + vy .^ 2); % Compute the absolute velocity of the signal    %degree
        aabs = sqrt(ax .^ 2 + ay .^ 2); % Compute the absolute acceleration of the signal    %pixel
        aabs1 = sqrt(ax1 .^ 2 + ay1 .^ 2); % Compute the absolute acceleration of the signal %degree
        
        vxabs = sqrt(vx .^ 2); % Compute the absolute value of the x-velocity      %degree     
        vyabs = sqrt(vy .^ 2); % Compute the absolute value of the y-velocity      %degree     

        axabs = sqrt(ax .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %pixel     
        ayabs = sqrt(ay .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %pixel    
     
        axabs1 = sqrt(ax1 .^ 2); %#ok<NASGU> % Compute the absolute value of the x-acceleration      %degree     
        ayabs1 = sqrt(ay1 .^ 2); %#ok<NASGU> % Compute the absolute value of the y-acceleration      %degree    
 
%         Cutoff = vCrit;  %#ok<NASGU> % solo per plot
        vCritx = vCrit; % Assign to the variable vCritx the critical value 
                        % of velocity used to detect a saccade
                        
        vCrity = vCrit; % Assign to the variable vCrity the critical value 
                        % of velocity used to detect a saccade
%         vxthreshold = [];
%         vythreshold = [];
         
        vxthreshold = filter_threshold_vel(vx,...
                      vCritx); % Call for the x-velocity threshold filter. Return a vector with null 
                               % elements where the velocity of the signal is not within the range 
                               % specified (vCritx). 
                  
        vythreshold = filter_threshold_vel(vy,...
                      vCrity); % Call for the y-velocity threshold filter. Return a vector with null 
                               % elements where the velocity of the signal is not within the range 
                               % specified (vCrity). 
      
        mvxthreshold = sqrt(vxthreshold .^ 2); %#ok<NASGU> % Normalize the velocity threshold vector
        mvythreshold = sqrt(vythreshold .^ 2); %#ok<NASGU> % Normalize the velocity threshold vector
        
        % -----------------------------------------------------------------
%         % ------------------- plotting during debugging of vabs velocity ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(vabs));        % range of vabs in ms.
%                plot(vRange/1000, vabs, 'k');              % plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(vabs)]./1000, [ vCrit vCrit], 'b--');
%                drawnow;
%                pause(1);
%         % ------------------- plotting during debugging ---------------------
%         
%         % ------------------- plotting during debugging of vx velocity ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(vx));        % range of vabs in ms.
%                plot(vRange/1000, vx, 'k');              % plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(vx)]./1000, [ vCrit vCrit], 'b--');
%                drawnow;
%                pause(1);
%         % ------------------- plotting during debugging
%         
        % ------------------- plotting during debugging of vy velocity ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(vy));        % range of vabs in ms.
%                plot(vRange/1000, vy, 'k');              % plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(vy)]./1000, [ vCrit vCrit], 'b--');
%                drawnow;
%                pause(1);
%         % ------------------- plotting during debugging
%         
%         % ------------------- plotting during debugging of vxabs velocity ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(vxabs));        % range of vabs in ms.
%                plot(vRange/1000, vxabs, 'k');              % plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(vxabs)]./1000, [ vCrit vCrit], 'b--');
%                drawnow;
%                pause(1);
        % ------------------- plotting during debugging
        
%         % ------------------- plotting during debugging of vyabs velocity ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(vyabs));        % range of vabs in ms.
%                plot(vRange/1000, vyabs, 'k');              % plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(vyabs)]./1000, [ vCrit vCrit], 'b--');
%                drawnow;
%                pause(1);
%         % ------------------- plotting during debugging
%                  
%         % ------------------- plotting during debugging of vxthreshold velocity ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(vxthreshold));        % range of vabs in ms.
%                plot(vRange/1000, vxthreshold, 'k');              % plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(vxthreshold)]./1000, [ vCrit vCrit], 'b--');
%                drawnow;
%                pause(1);
%         % ------------------- plotting during debugging
%         
%         % ------------------- plotting during debugging of mvxthreshold velocity ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(mvxthreshold));        % range of vabs in ms.
%                plot(vRange/1000, mvxthreshold, 'k');              % plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(mvxthreshold)]./1000, [ vCrit vCrit], 'b--');
%                drawnow;
%                pause(1);
%         % ------------------- plotting during debugging
%         
        % ------------------- plotting during debugging of acceleration ---------------------
%                figure;
%                hold on;
%                vRange = acqIntvl*(1:length(aabs));        % range of aabs in ms.
%                plot(vRange/1000, aabs, 'k');              %plot in pixel per secondi
%                hold on;
%                plot(acqIntvl*[1 length(aabs)]./1000, [ 0 0], 'c--');
%                drawnow;
%                pause(1);
        % ------------------- plotting during debugging
        % ----------------------------------------------------
        
        % Find the critical index to identify the saccades
        iCrit = find(vythreshold ~= 0); % Assign to the variable iCrit the 
                                        % index of all the elements of vxthreshold 
                                        % different from zero (saccades
                                        % instances).
       
        % If elements equal to zero were found go on with the following
        % computation
        if ~isempty(iCrit) % If there are saccades
            k = 1;  % Initialize the counter of the following while loop
            scnt = 0;  % Initialize a counter to take count of the saccades number
            s2 = 1;   
            
            while k <= length(iCrit)-3   
               
               % Check if there are three consecutive indexes having a velocity within the threshold
               if (iCrit(k) + 1 == iCrit(k + 1)) && (iCrit(k + 1) + 1 == iCrit(k + 2)) && (iCrit(k + 2) + 1 == iCrit(k + 3)) %& (iCrit(k+3)+1==iCrit(k+4)) & (iCrit(k+4)+1==iCrit(k+5))& (iCrit(k+5)+1==iCrit(k+6))& (iCrit(k+6)+1==iCrit(k+7)) & (iCrit(k+7)+1==iCrit(k+8))& (iCrit(k+8)+1==iCrit(k+9)) & (iCrit(k+9)+1==iCrit(k+10))    %condition if tre ara 10 index witn velocity > vCrit 
                   i1 = iCrit(k); % Assign to i1 the index of the current saccade                   
                   valuevx = sign(vythreshold(i1)); % Assign to valuevx the sign of the elements at index i1
                   i2c = min(find(vythreshold(i1 : end) == 0)); %#ok<MXFND> % Find the next index with a zero element 
                   i2c2 = min(find(sign(vythreshold(i1 : end)) ~= valuevx)); %#ok<MXFND> % Find the next index with a sign of the velocity opposite 
                                                                                         % to the velocity of the signal at the index i1
                   i2 = i1 + i2c - 2; % Define the final index where the vxthreshold goes back to zero
                   i2bis = i1 + i2c2 - 2; % Define the final index where the vxthreshold changes in the sign
                   i2 = min(i2, i2bis); % Overwrite i2 with the minimum index between the next change in sign 
                                        % and the zeros in the vector
                                        % vxthreshold.
                
                   i2 = min(i2, length(vabs)); % If the index is longer than the length of the velocity vector, 
                                               % overwrite it with the last index of th vector vabs.     
               
                   %i2 = min(i2bis, length(vabs)); basta questo
                   vPeak = max(vabs(i1 : i2)); % Find the peak of the absolute velocity between the i1 and i2
                   
                   vxPeak = max(vxabs(i1 : i2)); % Find the peak of the x-velocity between the i1 and i2 
                   
                   vyPeak = max(vyabs(i1 : i2)); % Find the peak of the y-velocity between the i1 and i2

                   aPeak = [min(aabs(i1 : i2)), max(aabs(i1 : i2))]; %#ok<NASGU> % Find the peak of the absolute acceleration between the i1 and i2 px
                   
                   aPeak1 = [min(aabs1(i1 : i2)), max(aabs1(i1 : i2))]; % Find the peak of the absolute acceleration between the i1 and i2 deg
                   
                   axPeak = [min(ax(i1 : i2)), max(ax(i1 : i2))]; %#ok<NASGU> % Find the peak of the x-acceleration between the i1 and i2 px
                   
                   ayPeak = [min(ay(i1 : i2)), max(ay(i1 : i2))]; %#ok<NASGU> % Find the peak of the y-acceleration between the i1 and i2 px
                  
                   axPeak1 = [min(ax1(i1 : i2)), max(ax1(i1 : i2))]; % Find the peak of the x-acceleration between the i1 and i2 deg
                   
                   ayPeak1 = [min(ay1(i1 : i2)), max(ay1(i1 : i2))]; % Find the peak of the y-acceleration between the i1 and i2 deg
  
                   iPeak = i1 + min(find(vabs(i1 : i2) == vPeak)) - 1;   %#ok<MXFND> % Find the index of the peak of the absolute 
                                                                                     % velocity between the i1 and i2 
                   ixPeak = i1 + min(find(vxabs(i1 : i2) == vxPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                      % x-velocity between the i1 and i2
                                                                                      
                   iyPeak = i1 + min(find(vyabs(i1 : i2) == vyPeak)) - 1; %#ok<MXFND> % Find the index of the peak of the  
                                                                                      % y-velocity between the i1 and i2      
                
                   s1 = i1;  % Assign to s1 the index of start of the current saccade (i1) 
                    
                   % Assign to s2 the ending index of the current saccade (i2)
                   if isempty(s2)
                       s2 = length(vabs);
                    
                   else
                       s2 = i2;
                    
                   end
                

                   % Compute the time in ms corresponding to the starting
                   % and ending indexes of the current saccades
                   ts1 = s1 * acqIntvl;  % [ms]
                   ts2 = s2 * acqIntvl;  % [ms]
                
                   %sprintf('ts2,ts1, saccadeMinDuration %i %i %i',ts2,ts1, saccadeMinDuration)
                
                  % Check if the current saccade is a saccade. Check the
                   % duration of the signal within the starting and ending
                   % indexes of the current saccade.
                   if ( (ts2-ts1) > saccadeMinDuration)
                       iNaN = find( ~isfinite(vabs(s1 : s2))); % Find the NaN elements of the signal 
                                                               % within the times s1 and s2.
                                                               
                       pctInvalid = (length(iNaN) / (s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                          % values of the vector within the times 
                                                                          % s1 and s2.
                    
                       iV = find(isfinite(vabs(s1 : s2))); % Find the indexes of the finite elements of the signal 
                                                           % within the times s1 and s2.
                                                           
                       iV = s1 + iV - 1; % Find the indexes of the finite elements of the signal 
                                         % within the times s1 and s2 with respect to the entire 
                                         % length of the gaze signal   
                       
                                         % Find the mean absolute velocity and acceleration
                       % of the current saccade.                        
                       if ~isempty(iV)
                           vMean = mean(vabs(iV));
                           aMean = mean(aabs(iV));             
                        
                       else
                           % If no finite elements are found the mean
                           % absolute velocity and acceleration are set to
                           % NaN values.
                           vMean = NaN;
                           aMean = NaN;
                           
                       end
                       
                       iNaNx = find( ~isfinite(vxabs(s1 : s2))); % Find the NaN elements of the x-velocity 
                                                                 % within the times s1 and s2
                                                                 
                       pctInvalidx = (length(iNaNx)/(s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                          % values of the x-velocity within the times 
                                                                          % s1 and s2.
                    
                
                        iVx = find(isfinite(vxabs(s1 : s2))); % Find the indexes of the finite elements of the signal 
                                                             % within the times s1 and s2.
                                                             
                       iVx = s1 + iVx - 1;	 % Find the indexes of the finite elements of the x-velocity 
                                             % within the times s1 and s2 with respect to the entire 
                                             % length of the gaze signal      
                
                       % Find the mean absolute x-velocity and x-acceleration
                       % of the current saccade. 
                       if ~isempty(iVx)
                           vxMean = mean(vxabs(iVx)); 
                           axMean = mean(ax1(iVx));            
                        
                       else
                           vxMean = NaN;
                           axMean = NaN;
                        
                       end
                    
                       iNaNy = find(~isfinite(vyabs(s1 : s2))); % Find the NaN elements of the y-velocity 
                                                                % within the times s1 and s2
                                                              
                       pctInvalidy = (length(iNaNy) / (s2 - s1 + 1)) * 100; % Compute the percentage of the NaN 
                                                                            % values of the y-velocity within the times 
                                                                            % s1 and s2.
                    
                
                       iVy = find(isfinite(vyabs(s1 : s2))); % Find the indexes of the finite elements of the y-velocity 
                                                           % within the times s1 and s2.
                                                           
                       iVy = s1 + iVy - 1;	% Find the indexes of the finite elements of the y-velocity 
                                            % within the times s1 and s2 with respect to the entire 
                                            % length of the gaze signal        
                       
                       % Find the mean absolute y-velocity and y-acceleration
                       % of the current saccade. 
                        if ~isempty(iVy)
                           vyMean = mean(vyabs(iVy)); 
                           ayMean = mean(ay1(iVy));             
                        
                       else
                           vyMean = NaN;
                           ayMean = NaN;
                        
                       end

                       sRT = (s1 - tT + 1) * acqIntvl;   % Find the starting time of the saccade  
                       ttP = (iPeak - tT + 1) * acqIntvl; % Find the time of the peak of the absolute velocity    
                       ttPx = (ixPeak - tT + 1) * acqIntvl; %#ok<NASGU> % Find the time of the peak of the x-velocity  
                       ttPy = (iyPeak - tT + 1) * acqIntvl; %#ok<NASGU> % Find the time of the peak of the y-velocity

%                 % --------------- plotting during debugging ------------------
%                 %                     
%                          %disp(sprintf('Trial %d  Saccade st: %.2f end: %.2f dur: %.1f ms vPk = %.1f vAvg = %.1f Inv = %.0f',...
%                          %i, ts1/1000, ts2/1000, ts2-ts1, vPeak, vMean, pctInvalid));
%                                                                      
%                          sRange = (s1:s2) * acqIntvl;
%                          plot(sRange/1000, vabs(s1:s2), 'r'); %plot in ms
%                          plot(iPeak*acqIntvl/1000, vabs(iPeak), 'ro');  % vPeak where search started
%                                                       
%                          plot([s1 s2]*acqIntvl/1000, [vCutoff vCutoff], 'r:');
%                          plot(ts1/1000, vCutoff, 'r^');
%                          plot(ts2/1000, vCutoff, 'r^');
%                          drawnow
%                  % --------------- plotting during debugging ----------------
                    
              
%                  vPeak = vPeak*acqRate/pixPerDegH; %  convert to deg/s
                 %vMean = vMean*acqRate/pixPerDegH; %  convert to deg/s
                
%                  vPeak = vPeak;   % non necessita di convert to deg/s
%                  vMean = vMean;   % non necessita di convert to deg/s
%                     
%                  vxPeak = vxPeak;   % non necessita di convert to deg/s
%                  vxMean = vxMean;   % non necessita di convert to deg/s
%                     
%                  vyPeak = vyPeak;   % non necessita di convert to deg/s
%                  vyMean = vyMean;   % non necessita di convert to deg/s
%                     
                 aPeak1 = aPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the absolute acceleration between the i1 and i2 deg
                 axPeak1 = axPeak1 * (acqRate * acqRate) / (pixPerDegH); % Find the peak of the x-acceleration between the i1 and i2 deg
                 ayPeak1 = ayPeak1 * (acqRate * acqRate) / (pixPerDegV); % Find the peak of the x-acceleration between the i1 and i2 deg

                 aMean = aMean * (acqRate * acqRate) / (pixPerDegH);  % Convert the mean acceleration to deg*s^2 
                 axMean = axMean * (acqRate * acqRate) / (pixPerDegH); % Convert the x mean acceleration to deg*s^2  
                 ayMean = ayMean * (acqRate * acqRate) / (pixPerDegV);  % Convert the y mean acceleration to deg*s^2
 
                       dSac = vMean * (ts2 - ts1) / 1000; % Compute the saccadic duration   
                       dSacx = vxMean * (ts2 - ts1) / 1000; % Compute the saccadic duration     
                       dSacy = vyMean * (ts2 - ts1) / 1000; % Compute the saccadic duration    
                    
                       x1 = trialx(s1);  % Compute the saccade x coordinate corrisponding to the start of the current saccade 
                       x2 = trialx(s2);  % Compute the saccade x coordinate corrisponding to the end of the current saccade 
                       y1 = trialy(s1);  % Compute the saccade y coordinate corrisponding to the start of the current saccade
                       y2 = trialy(s2);  % Compute the saccade y coordinate corrisponding to the end of the current saccade 
                 
                       distx = (x2 - x1) / pixPerDegH; % Compute the x saccadic distance (Pitagora)          
                       disty = (y2 - y1) / pixPerDegV; % Compute the y saccadic distance (Pitagora)     
                       distSac = sqrt(distx ^ 2 + disty ^ 2); % Compute the absolute saccadic distance (Pitagora)     
                       distSacx = abs(x2 - x1) / pixPerDegH;  % Convert the x saccadic distance to degree of visual angle (Pitagora)   
                       distSacy = abs(y2 - y1) / pixPerDegV;  % Convert the y saccadic distance to degree of visual angle (Pitagora)    
                  
                       % Compute the distance between the minimum and the
                       % miminum value and the starting coordinates of the
                       % saccade. (Same steps as the previos ones)
                       iNaNtx = find(~isfinite(trialx(s1 : s2)));
                       pctInvalidtx = (length(iNaNtx) / (s2 - s1 + 1)) * 100; 
                    
                       iVtx = find(isfinite(trialx(s1 : s2)));
                       iVtx = s1 + iVtx - 1;	       
                       
                       if ~isempty(iVtx)
                           distxPeakMax = max(trialx(iVtx));  
                           distxPeakMin = min(trialx(iVtx)); 
                           distxPeakRifMax = abs(x1 - distxPeakMax) / pixPerDegH;
                           distxPeakRifMin = abs(x1 - distxPeakMin) / pixPerDegH;
                           idistxPeakMax = s1 + max(find(trialx(s1 : s2) == distxPeakMax)) - 1;   %#ok<MXFND>
                           idistxPeakMin = s1 + max(find(trialx(s1 : s2) == distxPeakMin)) - 1;   %#ok<MXFND>
                        
                       else
                           distxPeakMax = NaN; %#ok<NASGU>
                           distxPeakMax = NaN; %#ok<NASGU>
                           distxPeakRifMax = NaN;
                           distxPeakRifMin = NaN; 
                           idistxPeakMax = NaN;
                           idistxPeakMin = NaN;
                                                  
                       end
                  
                       iNaNty = find(~isfinite(trialy(s1 : s2)));
                       pctInvalidty = (length(iNaNty) / (s2 - s1 + 1)) * 100; 
                       iVty = find(isfinite(trialy(s1 : s2)));
                       iVty = s1 + iVty - 1;      
                       
                       
                       if ~isempty(iVty)
                           distyPeakMax = max(trialy(iVty));   
                           distyPeakMin = min(trialy(iVty));     
                           distyPeakRifMax = abs(y1 - distyPeakMax) / pixPerDegV;
                           distyPeakRifMin = abs(y1 - distyPeakMin) / pixPerDegV;
                           idistyPeakMax = s1 + max(find(trialy(s1 : s2) == distyPeakMax)) - 1;   %#ok<MXFND>
                           idistyPeakMin = s1 + max(find(trialy(s1 : s2) == distyPeakMin)) - 1;   %#ok<MXFND>

                       else
% % % %                            distyPeakMax = NaN;
% % % %                            distyPeakMin = NaN;
                           distyPeakRifMax = NaN; 
                           distyPeakRifMin = NaN; 
                           idistyPeakMax = NaN; 
                           idistyPeakMin = NaN;
                           
                       end
                                      
                       durat =(ts2 - ts1); % Get the saccade duration
                        
                  %disp(sprintf('Trial %d dist %.1f %.1f\n', i, dSac, dist));
                    
                       scnt = scnt + 1;    % Increment the saccade counter

                              saccadeList = [saccadeList; i scnt s1 s2 vPeak vMean ... %6
                                             sRT ttP dSac distSac distSacx distSacy ... %12
                                             pctInvalid durat dSacx dSacy vxPeak vxMean ... %18
                                             vyPeak vyMean aMean pctInvalidx pctInvalidy ... %23
                                             pctInvalidtx pctInvalidty distxPeakRifMax ... %26
                                             distxPeakRifMin distyPeakRifMax distyPeakRifMin ... %29
                                             idistxPeakMax idistxPeakMin idistyPeakMax ... %32
                                             idistyPeakMin t1 t2 aPeak1 aMean axPeak1 axMean ... %41 (aPeak1 and axPeak1 are couples)
                                             ayPeak1 ayMean app.experiment.datarecorded(s1,4)]; %45 (ayPeak1 is a couple)
                                
                            
                       k = min(find(iCrit > s2)); %#ok<MXFND> % Get the next index for the saccade reaserch 
                                                  % (next index of the vector iCrit different from zero).
                      
                   else
                       k = k + 1; % If the duration of the signal within the starting 
                                  % and ending indexes of the current saccade is less 
                                  % than the duration threshold, skip the current saccade 
                                  % because it is not a asaccade.
                  
                   end         
               
               else
                   k = k + 1; % If there are not at least three consecutive elements belonging 
                              % to the current saccades is discarded.
               
               end  
            end 
            
        end 
        
    end 
    

    end
    end
    

end


    end  


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectfileButtonPushed(app, event)
            
            % Read the data file (.txt)
            [filename, pathname] = uigetfile ('*.txt', 'Select the file');
            
            % Print current selected file in the App interface
            app.CurrentFileEditField.Value = [pathname, filename];
            
            % Save informations in a struct
            app.experiment.fname = filename(1:end-4); 
        
            % Depending on the model of the eye-tracker, log file
            % containing raw gaze information may change. For this reason,
            % we specify rules to upload data from the original log file in
            % a standard format
            disp("Reading file, cleaning and pre-processing...");
            if strcmp(app.DeviceDropDown.Value, 'EyeLink Portable Duo') == 1
                eyelinktable = readtable([pathname, filename]);                 
                eyelinktable = eyelinktable(:, ...
                    {'AVERAGE_GAZE_X','AVERAGE_GAZE_Y', 'AVERAGE_PUPIL_SIZE', ...
                    'TIMESTAMP', 'TARGET_X_TARG1', 'TARGET_Y_TARG1'});                
                eyelinkmatrix = str2double(table2array(eyelinktable))/100;
                eyelinkmatrix(isnan(eyelinkmatrix)) = 0;
                app.experiment.datarecorded_raw = eyelinkmatrix;
                app.experiment.datarecorded_raw = [app.experiment.datarecorded_raw, zeros(size(app.experiment.datarecorded_raw, 1), 1)];
                %app.experiment.datarecorded_raw = risampledata(app.experiment.datarecorded_raw, 240);
            end
            
            app.experiment.Hz = 240;

            app.experiment.datarecorded = deblink_interp(app);
            app.experiment.datarecorded(:, 4) = app.experiment.datarecorded(:, 4) - app.experiment.datarecorded(1, 4);
            
            
            plot_signal(app);
            
            % saccade detection
            saccade_report(app);
            
            % then, for each saccade, compute some needed statistics
            add_column_type(app);
            add_column_gain(app);
            add_column_accuracy(app);
            add_column_latency(app);
            
            
            % create vector of manually deleted saccades
            app.experiment.deletedSaccades = [];
            
            % finally, update table in the gui
            updatetable(app);
        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)

            if isa(app.experiment, 'struct')
                updatetable(app);
                plot_signal(app);
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.experiment.datarecorded = deblink_interp(app);
            plot_signal(app);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            web('http://neurosense.wp.unisi.it/');
        end

        % Value changed function: MinAmplitudeSpinner
        function MinAmplitudeSpinnerValueChanged(app, event)
            updatetable(app);
        end

        % Value changed function: MaxAmplitudeSpinner
        function MaxAmplitudeSpinnerValueChanged(app, event)
            value = app.MaxAmplitudeSpinner.Value;
            updatetable(app);
        end

        % Value changed function: MinDurationSpinner
        function MinDurationSpinnerValueChanged(app, event)
            value = app.MinDurationSpinner.Value;
            updatetable(app);
        end

        % Value changed function: MaxDurationSpinner
        function MaxDurationSpinnerValueChanged(app, event)
            value = app.MaxDurationSpinner.Value;
            updatetable(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1);
            app.experiment.selectedRow = selectedRow;
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                app.UIAxes.YLim = [-50 1920+50];
            end
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                app.UIAxes.YLim = [-50 1080+50];
            end
            
            % set axis limits to zoom on the selected saccade
            timeStart = table2array(app.UITable.Data(selectedRow,3));
            timeEnd = timeStart + table2array(app.UITable.Data(selectedRow,4));
            app.UIAxes.XLim = [timeStart-500 timeEnd+500];
            
            % color the selected saccade differently

            plot_signal(app);

            saccade_color = [0 1 0];
            timestamp = app.experiment.datarecorded(:, 4);
            
            idT1 = find(timestamp == timeStart);
            idT2 = find(timestamp > timeEnd, 1, 'first');
            
            if strcmp(app.ExperimentDropDown.Value, "Horizontal Saccades") == 1
                
                % Plot Gaze X
                plot( ...
                    app.UIAxes, ...
                    timestamp(idT1:idT2), ...
                    app.experiment.datarecorded(idT1:idT2, 1), ...
                    'Color', saccade_color);
            end
            
            if strcmp(app.ExperimentDropDown.Value, "Vertical Saccades") == 1
                
                % Plot Gaze Y
                plot( ...
                    app.UIAxes, ...
                    timestamp(idT1:idT2), ...
                    app.experiment.datarecorded(idT1:idT2, 2), ...
                    'Color', saccade_color);
            end
            
        end

        % Button pushed function: RemoveselectedsaccadeButton
        function RemoveselectedsaccadeButtonPushed(app, event)
            selecteRow = app.experiment.selectedRow;
            selectedSample = table2array(app.UITable.Data(selecteRow,1));
            app.experiment.deletedSaccades = [app.experiment.deletedSaccades selectedSample];
            updatetable(app);
            
            
        end

        % Value changed function: SaccTypeDropDown
        function SaccTypeDropDownValueChanged(app, event)
            value = app.SaccTypeDropDown.Value;
            updatetable(app);
        end

        % Value changed function: SavetableButton
        function SavetableButtonValueChanged(app, event)
            value = app.SavetableButton.Value;
            [file, path] = uiputfile('*.xlsx');
            app.UITable.Data.Properties.VariableNames = app.UITable.ColumnName;
            writetable(app.UITable.Data, strcat(path, file));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.experiment.deletedSaccades = app.experiment.deletedSaccades(1:end-1);
            updatetable(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Gaze')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Saccade ID'; 'Sample ID'; 'Time (start)'; 'Duration'; 'Amplitude'; 'Peak velocity'; 'Mean velocity'; 'Saccade Type'; 'Gain'; 'Accuracy'; 'Latency'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CurrentFileEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentFileEditField.Editable = 'off';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.DeviceDropDownLabel = uilabel(app.UIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.UIFigure);
            app.DeviceDropDown.Items = {'EyeLink Portable Duo'};
            app.DeviceDropDown.Value = 'EyeLink Portable Duo';
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDown.Items = {'Horizontal Saccades', 'Vertical Saccades'};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.ExperimentDropDown.Value = 'Horizontal Saccades';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.UpdateButton = uibutton(app.OptionsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.BlinkoffsetEditFieldLabel = uilabel(app.OptionsPanel);
            app.BlinkoffsetEditFieldLabel.Text = 'Blink offset';
            app.BlinkoffsetEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.BlinkoffsetEditField.Limits = [0 500];
            app.BlinkoffsetEditField.RoundFractionalValues = 'on';
            app.ScreenResolutionEditFieldLabel = uilabel(app.OptionsPanel);
            app.ScreenResolutionEditFieldLabel.Text = 'Screen Resolution';
            app.ScreenResolutionCols = uieditfield(app.OptionsPanel, 'numeric');
            app.ScreenResolutionCols.Value = 1920;
            app.ScreenResolutionRows = uieditfield(app.OptionsPanel, 'numeric');
            app.ScreenResolutionRows.Value = 1080;
            app.xEditFieldLabel = uilabel(app.OptionsPanel);
            app.xEditFieldLabel.Text = 'x';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'ns.png';
            app.MinAmplitudeSpinnerLabel = uilabel(app.UIFigure);
            app.MinAmplitudeSpinnerLabel.Text = 'Min. Amplitude';
            app.MinAmplitudeSpinner = uispinner(app.UIFigure);
            app.MinAmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @MinAmplitudeSpinnerValueChanged, true);
            app.MinDurationSpinnerLabel = uilabel(app.UIFigure);
            app.MinDurationSpinnerLabel.Text = 'Min. Duration';
            app.MinDurationSpinner = uispinner(app.UIFigure);
            app.MinDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @MinDurationSpinnerValueChanged, true);
            app.MaxAmplitudeSpinnerLabel = uilabel(app.UIFigure);
            app.MaxAmplitudeSpinnerLabel.Text = 'Max. Amplitude';
            app.MaxAmplitudeSpinner = uispinner(app.UIFigure);
            app.MaxAmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxAmplitudeSpinnerValueChanged, true);
            app.MaxAmplitudeSpinner.Value = 25;
            app.MaxDurationSpinnerLabel = uilabel(app.UIFigure);
            app.MaxDurationSpinnerLabel.Text = 'Max. Duration';
            app.MaxDurationSpinner = uispinner(app.UIFigure);
            app.MaxDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxDurationSpinnerValueChanged, true);
            app.MaxDurationSpinner.Value = 500;
            app.FiltersLabel = uilabel(app.UIFigure);
            app.FiltersLabel.Text = 'Filters:';
            app.ReportLabel = uilabel(app.UIFigure);
            app.ReportLabel.Text = 'Report';
            app.NeuroSenseJointLabSienaSIItalyLabel = uilabel(app.UIFigure);
            app.NeuroSenseJointLabSienaSIItalyLabel.Text = {'NeuroSense Joint Lab.'; 'Siena, SI (Italy)'};
            app.SavetableButton = uibutton(app.UIFigure, 'state');
            app.SavetableButton.ValueChangedFcn = createCallbackFcn(app, @SavetableButtonValueChanged, true);
            app.SavetableButton.Text = 'Save table';
            app.RemoveselectedsaccadeButton = uibutton(app.UIFigure, 'push');
            app.RemoveselectedsaccadeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedsaccadeButtonPushed, true);
            app.RemoveselectedsaccadeButton.Text = 'Remove selected saccade';
            app.SaccTypeDropDownLabel = uilabel(app.UIFigure);
            app.SaccTypeDropDownLabel.Text = 'Sacc. Type';
            app.SaccTypeDropDown = uidropdown(app.UIFigure);
            app.SaccTypeDropDown.Items = {'All', '10', '18', '8'};
            app.SaccTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SaccTypeDropDownValueChanged, true);
            app.SaccTypeDropDown.Value = 'All';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '⎌';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaccadeAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end